classdef BlackJack_Game_Skeleton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        P2RollNumbersLabel              matlab.ui.control.Label
        P1RollNumbersLabel              matlab.ui.control.Label
        WinCapEditField                 matlab.ui.control.EditField
        ScoretoWinEditFieldLabel_2      matlab.ui.control.Label
        HouseEditField                  matlab.ui.control.EditField
        HouseScorePointsEditFieldLabel  matlab.ui.control.Label
        P2ScorePointsEditField          matlab.ui.control.EditField
        P2ScorePointsEditField_2Label   matlab.ui.control.Label
        P1ScorePointsEditField          matlab.ui.control.EditField
        P1ScorePointsEditField_2Label   matlab.ui.control.Label
        Announcement                    matlab.ui.control.Label
        Button_4                        matlab.ui.control.Button
        Image5_2                        matlab.ui.control.Image
        Image6                          matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        Button_3                        matlab.ui.control.StateButton
        Image2                          matlab.ui.control.Image
        RollsLabel_3                    matlab.ui.control.Label
        P1RollsLabel                    matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
        UITable                         matlab.ui.control.Table
        Button_2                        matlab.ui.control.Button
        Image4                          matlab.ui.control.Image
    end


    properties (Access = private)
        playerCount;
        isRollClicked = false;
        holded = false;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winCapValue, PlayersValue)

            % Initialize variables
            app.WinCapEditField.Value = string(winCapValue);
            app.playerCount = string(PlayersValue);

            maxPlayers = round(str2double(PlayersValue));
            players(1, maxPlayers) = Player();  % Preallocate player array
            for i = 1:maxPlayers
                players(i) = Player("P" + string(i));
            end

            DiceDealer = Dice();
            DiceDealer.Name = "House";

            % Helper to reset scores and rolling state
            function clearScores(plrs, house)
                house.Score = 0;
                for b = 1:length(plrs)
                    plrs(b).Score = 0;
                    plrs(b).IsDoneRolling = 0;
                end
            end

            isDone = false;
            roundNumber = 0;

            while ~isDone
                clearScores(players, DiceDealer);
                app.Button_4.Visible = "on";
                % Reset rolls table UI at start of round
                app.UITable.Data = {};
                roundNumber = roundNumber + 1;

                % Initialize roll history for this round
                playerRolls = cell(1, maxPlayers);
                for p = 1:maxPlayers
                    playerRolls{p} = [];
                end

                disp('!!! Table and playerRolls reset');
                
                % --- First 2 Rolls for Players ---
                for rollCount = 1:2
                    for i = 1:maxPlayers
                        app.Button_4.Visible = "on";
                        app.isRollClicked = false;

                        app.Announcement.Text = sprintf('%s, it is your turn to roll', players(i).Name);
                        app.Announcement.Visible = true;

                        while isvalid(app) && ~app.isRollClicked
                            pause(0.1); drawnow;
                        end

                        if isvalid(app) && isprop(app, 'Announcement') && isvalid(app.Announcement)
                            app.Announcement.Visible = false;
                        end

                        % DICE ROLL ANIMATION

                        app.Image5.ImageSource = fullfile('C:\Users\jemse\Downloads\Dice_Blackjack-main\Dice_Blackjack-main', 'rolling.gif');

                        
                        % DICE ROLL SOUND EFFECT

                        [y, Fs] = audioread('dice_roll.wav');
                        sound(y, Fs);

                        pause(0.6);

                        % Roll logic
                        roll = randi([2,14]);
                        if ismember(roll, [10,12,13,14])
                            roll = 10;
                        elseif roll == 11
                            choice = inputdlg('Choose 1 or 11 for your Ace:', 'Ace Choice', [1 45]);
                            rollChoice = str2double(choice{1});
                            if ismember(rollChoice, [1, 11])
                                roll = rollChoice;
                            else
                                roll = 11; % Default if invalid
                            end
                        end


                        % DICE ROLL IMAGE CHANGE

                        appFolder = fileparts(mfilename('fullpath'));  % Get folder where your app code lives
                        app.Image5.ImageSource = fullfile(appFolder, sprintf('Dice-%d.png', roll));  % Set dice image based on roll
 

                        fprintf('%s rolled a %d!\n', players(i).Name, roll);
                        players(i).UpdateScore(roll);

                        % Record roll for UI
                        playerRolls{i} = [playerRolls{i}, roll];

                        % Update Player 1 Table
                        if maxPlayers >= 1
                            app.UITable.Data = num2cell(playerRolls{1});
                            app.UITable.ColumnName = compose('%d', 1:length(playerRolls{1}));
                        end
                        
                        % Update Player 2 Table
                        if maxPlayers >= 2
                            app.UITable_2.Data = num2cell(playerRolls{2});
                            app.UITable_2.ColumnName = compose('%d', 1:length(playerRolls{2}));
                        end
                        
                        disp('Roll added to table:');
                        disp(app.UITable.Data);
                        % Update player points UI
                        if i == 1
                            app.P1ScorePointsEditField.Value = sprintf('%d , %d', players(i).Score, players(i).Points);
                        elseif i == 2
                            app.P2ScorePointsEditField.Value = sprintf('%d , %d', players(i).Score, players(i).Points);
                        end
                    end

                    % House roll per iteration
                    DiceDealer.Roll();
                    houseSum = DiceDealer.Score;
                    houseScore = DiceDealer.Points;
                    app.HouseEditField.Value = sprintf('%d , %d', DiceDealer.Score, DiceDealer.Points);
                end

                % --- Player Rolling Phase Until Hold or Bust ---
                while any([players.IsDoneRolling] == 0)
                    for i = 1:maxPlayers
                        if players(i).IsDoneRolling
                            continue;
                        end

                        app.Announcement.Text = sprintf('%s, your score is %d', players(i).Name, players(i).Score);
                        app.Announcement.Visible = true;
                        pause(1.5);

                        app.Announcement.Text = sprintf('%s, do you want to roll or hold?', players(i).Name);
                        app.Button_3.Visible = "on";  % Hold button
                        app.Button_4.Visible = "on";  % Roll button
                        app.Button_3.Enable = 'on';     % Enable hold button
                        app.Button_4.Enable = 'on';     % Enable roll button
                        app.isRollClicked = false;

                        while ~app.isRollClicked
                            pause(0.1); drawnow;
                        end

                        app.Button_3.Visible = "off";  % Hold button
                        app.Button_4.Visible = "off";

                        if app.holded
                            app.Announcement.Text = sprintf('%s holds with score %d.', players(i).Name, players(i).Score);
                            app.holded = false;
                            players(i).IsDoneRolling = 1;
                            pause(1.5);
                            continue;
                        end

                        % ROLLING ANIMATION

                        app.Image5.ImageSource = fullfile('C:\Users\jemse\Downloads\Dice_Blackjack-main\Dice_Blackjack-main', 'rolling.gif');

                        
                        % DICE ROLL SOUND EFFECT

                        [y, Fs] = audioread('dice_roll.wav');
                        sound(y, Fs);

                        pause(0.6);

                        % Player chooses to roll
                        roll = randi([2,14]);
                        if ismember(roll, [10,12,13,14])
                            roll = 10;
                        elseif roll == 11
                            choice = inputdlg('Choose 1 or 11 for your Ace:', 'Ace Choice', [1 45]);
                            rollChoice = str2double(choice{1});
                            if ismember(rollChoice, [1, 11])
                                roll = rollChoice;
                            else
                                roll = 11;
                            end
                        end


                        % DICE ROLL IMAGE CHANGE

                       appFolder = fileparts(mfilename('fullpath'));  % Get folder where your app code lives
                       app.Image5.ImageSource = fullfile(appFolder, sprintf('Dice-%d.png', roll));  % Set dice image based on roll


                        fprintf('%s rolled a %d!\n', players(i).Name, roll);
                        players(i).UpdateScore(roll);

                        % Add roll to roll history
                        playerRolls{i} = [playerRolls{i}, roll];

                        % Update Player 1 Table
                        if maxPlayers >= 1
                            app.UITable.Data = num2cell(playerRolls{1});
                            app.UITable.ColumnName = compose('%d', 1:length(playerRolls{1}));
                        end
                        
                        % Update Player 2 Table
                        if maxPlayers >= 2
                            app.UITable_2.Data = num2cell(playerRolls{2});
                            app.UITable_2.ColumnName = compose('%d', 1:length(playerRolls{2}));
                        end
                        if i == 1
                            app.P1ScorePointsEditField.Value = sprintf('%d , %d', players(i).Score, players(i).Points);
                            app.P2ScorePointsEditField.Value = sprintf('%d , %d', players(i).Score, players(i).Points);
                        end

                        if players(i).Score > 21
                            fprintf('%s busted!\n', players(i).Name);
                            players(i).FinalScore = 0;
                            players(i).IsDoneRolling = 1;
                        end
                    end
                end

                % --- House Final Roll ---

                while DiceDealer.Score < 17
                    DiceDealer.Roll();
                    pause(1); % Optional: simulate delay
                    fprintf('House rolled. Current total: %d\n', DiceDealer.Score);
                end

                houseSum = DiceDealer.Score;
                houseScore = DiceDealer.Points;
                app.HouseEditField.Value = sprintf('%d , %d', DiceDealer.Score, DiceDealer.Points);

                % --- Evaluate Round Outcome ---
                if DiceDealer.Score > 21
                    fprintf('House busted!\n');
                    for i = 1:maxPlayers
                        if players(i).Score > 0 && players(i).Score <= 21
                            players(i).Points = players(i).Points + 1;
                        end
                        players(i).Score = 0;
                    end
                else
                    houseWins = true;
                    for i = 1:maxPlayers
                        if players(i).Score <= 21 && players(i).Score > DiceDealer.Score
                            players(i).Points = players(i).Points + 1;
                            houseWins = false; % Someone beat the house
                        end
                        players(i).Score = 0;
                    end

                    if houseWins
                        DiceDealer.Points = DiceDealer.Points + 1;
                        fprintf('House wins the round!\n');
                    end
                end

                % Update house points on UI
                app.HouseEditField.Value = sprintf('%d , %d', DiceDealer.Score, DiceDealer.Points);

                % Update player points after round
                for i = 1:maxPlayers
                    if i == 1
                        app.P1ScorePointsEditField.Value = sprintf('%d , %d', players(i).Score, players(i).Points);
                    elseif i == 2
                        app.P2ScorePointsEditField.Value = sprintf('%d , %d', players(i).Score, players(i).Points);
                    end
                end


                % --- Check for a winner after round ends ---
                cap = str2double(app.WinCapEditField.Value);
                winner = Winner(players, cap, DiceDealer);

                if ~isempty(winner)
                    uialert(app.UIFigure, sprintf('%s wins!', winner), 'Game Over');
                    BlackJack_WinScreen_Skeleton(winner);
                    return;  % Stop the game loop after someone wins
                end

                % Reset for next round

                for i = 1:maxPlayers
                    players(i).IsDoneRolling = 0;
                    players(i).Score = 0;
                end
                DiceDealer.Score = 0;
            end


        end

        % Button pushed function: Button_2
        function Exit(app, event)
            delete(app)
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            disp('ROLL PRESSED');

            app.isRollClicked = true;
            app.holded = false;
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            disp('HOLD PRESSED');
            app.holded = true;
            app.isRollClicked = true;
            app.Button_3.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Theater Background.png');
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Exit, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'Exit.png');
            app.Button_2.Text = '';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'1st'; '2nd'; '3rd'; '4th'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'1st'; '2nd'; '3rd'; '4th'};
            app.UITable_2.RowName = {};
            app.P1RollsLabel = uilabel(app.UIFigure);
            app.P1RollsLabel.Text = 'P1 Rolls';
            app.RollsLabel_3 = uilabel(app.UIFigure);
            app.RollsLabel_3.Text = 'Rolls';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Computer.png');
            app.Button_3 = uibutton(app.UIFigure, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Visible = 'off';
            app.Button_3.Icon = fullfile(pathToMLAPP, 'Hold.png');
            app.Button_3.Text = '';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Dice-11.png');
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.Visible = 'off';
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'Dice-11.png');
            app.Image5_2 = uiimage(app.UIFigure);
            app.Image5_2.Visible = 'off';
            app.Image5_2.ImageSource = fullfile(pathToMLAPP, 'Dice-11.png');
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = fullfile(pathToMLAPP, 'Roll.png');
            app.Button_4.Visible = 'off';
            app.Button_4.Text = '';
            app.Announcement = uilabel(app.UIFigure);
            app.Announcement.Visible = 'off';
            app.Announcement.Text = 'P1, it is your turn to roll...';
            app.P1ScorePointsEditField_2Label = uilabel(app.UIFigure);
            app.P1ScorePointsEditField_2Label.Text = 'P1 (Score, Points):';
            app.P1ScorePointsEditField = uieditfield(app.UIFigure, 'text');
            app.P1ScorePointsEditField.Editable = 'off';
            app.P1ScorePointsEditField.Value = '0 , 0';
            app.P2ScorePointsEditField_2Label = uilabel(app.UIFigure);
            app.P2ScorePointsEditField_2Label.Text = 'P2 (Score, Points);';
            app.P2ScorePointsEditField = uieditfield(app.UIFigure, 'text');
            app.P2ScorePointsEditField.Editable = 'off';
            app.P2ScorePointsEditField.Value = '0 , 0';
            app.HouseScorePointsEditFieldLabel = uilabel(app.UIFigure);
            app.HouseScorePointsEditFieldLabel.Text = 'House (Score, Points):';
            app.HouseEditField = uieditfield(app.UIFigure, 'text');
            app.HouseEditField.Editable = 'off';
            app.HouseEditField.Value = '0 , 0';
            app.ScoretoWinEditFieldLabel_2 = uilabel(app.UIFigure);
            app.ScoretoWinEditFieldLabel_2.Text = 'Score to Win:';
            app.WinCapEditField = uieditfield(app.UIFigure, 'text');
            app.WinCapEditField.Editable = 'off';
            app.WinCapEditField.Value = '0 , 0';
            app.P1RollNumbersLabel = uilabel(app.UIFigure);
            app.P1RollNumbersLabel.Text = '                 P1 Roll Numbers';
            app.P2RollNumbersLabel = uilabel(app.UIFigure);
            app.P2RollNumbersLabel.Text = '                 P2 Roll Numbers';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BlackJack_Game_Skeleton(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end